classdef ChromSmoothPeakDetectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BaselineButton               matlab.ui.control.Button
        AlgorithmsDropDown           matlab.ui.control.DropDown
        AlgorithmsDropDownLabel      matlab.ui.control.Label
        DownloadPeaklocationsButton  matlab.ui.control.Button
        DownloadsmoothedchromatogramButton  matlab.ui.control.Button
        PeakdetectionButton          matlab.ui.control.Button
        DenoiseButton                matlab.ui.control.Button
        LoaddataButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x
        y
        z
        apex_loc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file, path, indx] = uigetfile;
            filename = [path file];
            A = readmatrix(filename);
            app.x = A(:,1)';
            app.y = A(:,2)'; 
            plot(app.UIAxes,app.x,app.y);
        end

        % Button pushed function: BaselineButton
        function BaselineButtonPushed(app, event)
           addpath(genpath('C:\Users\emery\OneDrive\Documents\MATLAB\Asymmetrical least squares'))
            app.y = automated_erPLS(app.y);
            plot(app.UIAxes,app.x,app.y);
            legend(app.UIAxes,'baseline corrected chromatogram')
        end

        % Button pushed function: DenoiseButton
        function DenoiseButtonPushed(app, event)
            addpath(genpath("C:\Users\emery\OneDrive\Documents\MATLAB\Smoothening Bayesian Optimization\Functions"))
            [nrow,ncol] = size(app.y);
            if nrow > ncol 
             app.y = app.y';
            end
            switch app.AlgorithmsDropDown.Value
                case 'LOOCV + BO'
                    app.z = LOOCVBayOpt(app.y);
                case 'V-Curve + Grid'
                    lambdas = logspace(-3,10,25);
                    degrees = [1 2 3];
                    app.z = gridCurves(app.y,lambdas,degrees);
                case 'V-Curve + BO'
                    app.z = vCurveBayOpt(app.y);
            end

            plot(app.UIAxes,app.x,app.y,app.x,app.z,'r');
            legend(app.UIAxes,'original chromatogram','smoothed chromatogram')
        end

        % Button pushed function: PeakdetectionButton
        function PeakdetectionButtonPushed(app, event)
            % first derivative
            dz = diff(app.z)';
            % smoothening of first derivative
            dzsmooth = vCurveBayOpt(dz);
            %second derivative
            dz2 = diff(dzsmooth);
            dz2 = dz2';
            %smoothing of second derivative
            dz2smooth = vCurveBayOpt(dz2);
            % Find minima in second derivative
            app.apex_loc = findMin(dz2smooth,app.y,2,20);
            plot(app.UIAxes,app.x,app.y,'b',app.x,app.z,'r',app.x(app.apex_loc),app.y(app.apex_loc),'*black');
            legend(app.UIAxes,"original chromatogram","Smoothed chromatogram","Peak locations");
        end

        % Button pushed function: DownloadsmoothedchromatogramButton
        function DownloadsmoothedchromatogramButtonPushed(app, event)
            chrom = app.z;
            writematrix(chrom,'smoothChrom.xlsx')
            filter = {'*.xlsx'};
            [file, path] = uiputfile(filter);
        end

        % Button pushed function: DownloadPeaklocationsButton
        function DownloadPeaklocationsButtonPushed(app, event)
            peak_loc = app.apex_loc;
            writematrix(peak_loc,'peak_loc.xlsx')
            filter = {'*.xlsx'};
            [file, path] = uiputfile(filter);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Chromatograms')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Absorbance')
            zlabel(app.UIAxes, 'Z')
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.DenoiseButton = uibutton(app.UIFigure, 'push');
            app.DenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButtonPushed, true);
            app.DenoiseButton.Text = 'Denoise';
            app.PeakdetectionButton = uibutton(app.UIFigure, 'push');
            app.PeakdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @PeakdetectionButtonPushed, true);
            app.PeakdetectionButton.Text = 'Peak detection';
            app.DownloadsmoothedchromatogramButton = uibutton(app.UIFigure, 'push');
            app.DownloadsmoothedchromatogramButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadsmoothedchromatogramButtonPushed, true);
            app.DownloadsmoothedchromatogramButton.Text = 'Download smoothed chromatogram ';
            app.DownloadPeaklocationsButton = uibutton(app.UIFigure, 'push');
            app.DownloadPeaklocationsButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadPeaklocationsButtonPushed, true);
            app.DownloadPeaklocationsButton.Text = 'Download Peak locations';
            app.AlgorithmsDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmsDropDownLabel.Text = 'Algorithms';
            app.AlgorithmsDropDown = uidropdown(app.UIFigure);
            app.AlgorithmsDropDown.Items = {'LOOCV + BO', 'V-Curve + Grid', 'V-Curve + BO'};
            app.AlgorithmsDropDown.Value = 'LOOCV + BO';
            app.BaselineButton = uibutton(app.UIFigure, 'push');
            app.BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineButtonPushed, true);
            app.BaselineButton.Text = 'Baseline ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChromSmoothPeakDetectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end